classdef TAM_01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TAM10UIFigure        matlab.ui.Figure
        CalculateButton      matlab.ui.control.Button
        KLabel               matlab.ui.control.Label
        TmEditField          matlab.ui.control.NumericEditField
        TmEditFieldLabel     matlab.ui.control.Label
        TempEditField        matlab.ui.control.NumericEditField
        TempEditFieldLabel   matlab.ui.control.Label
        DOYEditField         matlab.ui.control.NumericEditField
        DOYEditFieldLabel    matlab.ui.control.Label
        DateDatePicker       matlab.ui.control.DatePicker
        DateDatePickerLabel  matlab.ui.control.Label
        HEditField           matlab.ui.control.NumericEditField
        HEditFieldLabel      matlab.ui.control.Label
        LatEditField         matlab.ui.control.NumericEditField
        LatEditFieldLabel    matlab.ui.control.Label
        LonEditField         matlab.ui.control.NumericEditField
        LonEditFieldLabel    matlab.ui.control.Label
        ButtonGroup          matlab.ui.container.ButtonGroup
        Button_2             matlab.ui.control.RadioButton
        Button               matlab.ui.control.RadioButton
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
       
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % APP居中
            Position_Screen = get(0,"ScreenSize");
            Position_App = app.TAM10UIFigure.Position;
            Position_App(1) = (Position_Screen(3)-Position_App(3))/2;
            Position_App(2) = (Position_Screen(4)-Position_App(4))/2;
            app.TAM10UIFigure.Position = Position_App;
            % 坐标轴画西北地区
            shp_path='D:\develop\TAM1\area\all.shp';  
            Map = shaperead(shp_path);             
            mapshow(app.UIAxes,Map,'FaceColor','none','EdgeColor','black');
            hold(app.UIAxes,"on");
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            warning off
            % 赋值 经度、纬度、温度（K）、高度（m）
            lon  = app.LonEditField.Value;
            lat  = app.LatEditField.Value;
             H   = app.HEditField.Value;
            Temp = app.TempEditField.Value;                                                      
            
            % assignin("base","lon",lon);  double类型 90
            
            % 输入日期，日期转DOY
            Date = app.DateDatePicker.Value;
            % 若未输入日期，则DOY默认为999，错误！
            if isnat(Date)
                app.DOYEditField.Value = 999;
                % errordlg("请输入日期！","错误");
            else
                app.DOYEditField.Value = day(Date,"dayofyear");
                Doy = app.DOYEditField.Value;
            end

            % 根据lon lat H Ts DOY 计算Tm
            wait = waitbar(0,'Calculating………');
            % 坐标轴画图
            id = chose_area(lon,lat);
            shp_path = strcat('D:\develop\TAM1\area\',num2str(id),'.shp');
            Map = shaperead(shp_path);
            mapshow(app.UIAxes,Map,'FaceColor',[0.75 0.75 0.75]);   
            title(app.UIAxes,Map.x0xE70xAB0x990xE50x900x8D);
            subtitle(app.UIAxes,Map.x0xE70xAB0x990xE70x820xB90xE50x8F0xB7);
            hold(app.UIAxes,"on");
            scatter(app.UIAxes,lon,lat,18,"red","filled");
            % 应当固定坐标框大小
            waitbar(0.2,wait,'Calculating………')

            % 调用顾及周期的函数（id，Ts，DOY）-----------------------------
            Tm_Ts = ts2tm(id,Doy,Temp);
            assignin("base","ts2tm",Tm_Ts);
            waitbar(0.4,wait,'Calculating………')
            
            % 调用ERA5-----------------------------------------------------
            Tm_nc = nc2tm(lat,lon,H,Doy);
            assignin("base","nc",Tm_nc);
            waitbar(0.6,wait,'Calculating………')
            
            % 调用GGOS-----------------------------------------------------
            Tm_vmf = vmf2tm(lat,lon,H,Doy);
            waitbar(0.8,wait,'Calculating………')
            
            % 计算K值，得到最终TAM模型估值----------------------------------
            K = k_cal(lon,lat);
            assignin("base","k",K);

            app.TmEditField.Value = Tm_nc *(1/(2+K)) + Tm_vmf *(1/(2+K)) + Tm_Ts *(K/(2+K)) ;
            waitbar(1,wait,'Calculate!')

            delete(wait);
        end

        % Window key press function: TAM10UIFigure
        function TAM10UIFigureWindowKeyPress(app, event)
            % 监听键盘事件
            key = event.Key;
            % 考虑用Switch语句进行判断
            switch key
                case 'return'  % 代表回车
                    CalculateButtonPushed(app, event);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.TAM10UIFigure = uifigure('Visible', 'off');
            app.TAM10UIFigure.Name = 'TAM.1.0';
            app.TAM10UIFigure.Icon = fullfile(pathToMLAPP, 'TAM.png');
            app.TAM10UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @TAM10UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.TAM10UIFigure);
            title(app.UIAxes, 'Voronoi region')
            xlabel(app.UIAxes, 'lon')
            ylabel(app.UIAxes, 'lat')
            app.UIAxes.XTickLabel = {'70'; '80'; '90'; '100'; '110'; '120'};
            app.UIAxes.YTickLabel = {'30'; '35'; '40'; '45'; '50'; '55'};
            app.ButtonGroup = uibuttongroup(app.TAM10UIFigure);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '西北地区';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '全国区域';
            app.LonEditFieldLabel = uilabel(app.TAM10UIFigure);
            app.LonEditFieldLabel.Text = 'Lon';
            app.LonEditField = uieditfield(app.TAM10UIFigure, 'numeric');
            app.LonEditField.Tooltip = {'Please enter a longitude value from 75 to 115'};
            app.LatEditFieldLabel = uilabel(app.TAM10UIFigure);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LatEditField = uieditfield(app.TAM10UIFigure, 'numeric');
            app.LatEditField.Tooltip = {'Please enter a latitude value from 30 to 50'};
            app.HEditFieldLabel = uilabel(app.TAM10UIFigure);
            app.HEditFieldLabel.Text = 'H';
            app.HEditField = uieditfield(app.TAM10UIFigure, 'numeric');
            app.HEditField.Tooltip = {'Please enter the target height'};
            app.DateDatePickerLabel = uilabel(app.TAM10UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.TAM10UIFigure);
            app.DOYEditFieldLabel = uilabel(app.TAM10UIFigure);
            app.DOYEditFieldLabel.Text = 'DOY';
            app.DOYEditField = uieditfield(app.TAM10UIFigure, 'numeric');
            app.DOYEditField.Editable = 'off';
            app.TempEditFieldLabel = uilabel(app.TAM10UIFigure);
            app.TempEditFieldLabel.Text = 'Temp';
            app.TempEditField = uieditfield(app.TAM10UIFigure, 'numeric');
            app.TempEditField.Tooltip = {'Please enter the Kelvin temperature'};
            app.TmEditFieldLabel = uilabel(app.TAM10UIFigure);
            app.TmEditFieldLabel.Text = 'Tm';
            app.TmEditField = uieditfield(app.TAM10UIFigure, 'numeric');
            app.TmEditField.ValueDisplayFormat = '%.4f';
            app.TmEditField.Editable = 'off';
            app.KLabel = uilabel(app.TAM10UIFigure);
            app.KLabel.Text = 'K';
            app.CalculateButton = uibutton(app.TAM10UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate！';
            % Show the figure after all components are created
            app.TAM10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TAM_01

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TAM10UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.TAM10UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TAM10UIFigure)
        end
    end
end